classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ClearButton      matlab.ui.control.Button
        ResultLabel      matlab.ui.control.Label
        Button           matlab.ui.control.StateButton
        ExpressionLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        recording = false
        expression = ""
        lastToken = ""
        net = load('op_dig_89_62.mat');
    end
    
    methods (Access = private)
        
        function [symbol, predictedLabel] = recognizeSpeechSymbol(app)
            %% 1. Settings (must match training)
            Fs = 16000;
            duration = 1.0;
            spectrogramDimensions = [64, 64];
            numMelBands = spectrogramDimensions(1);
            targetTimeLength = spectrogramDimensions(2);
            winSize = round(0.025 * Fs);
            hopSize = round(0.010 * Fs);
            nfft = 512;
        
            %% 2. Record Audio
            recObj = audiorecorder(Fs, 16, 1);
            recordblocking(recObj, duration);
            y = getaudiodata(recObj);
        
            % Normalize audio amplitude
            max_amp = max(abs(y));
            if max_amp > 0.01
                y = y / max_amp;
            end
        
            %% 3. Spectrogram
            [S, ~, ~] = melSpectrogram(y, Fs, ...
                'Window', hamming(winSize, 'periodic'), ...
                'OverlapLength', winSize - hopSize, ...
                'FFTLength', nfft, ...
                'NumBands', numMelBands, ...
                'FrequencyRange', [0, Fs/2]);
        
            S_dB = 20*log10(abs(S) + 1e-6);
        
            % Resize to 64×64
            S_resized = imresize(S_dB, [numMelBands, targetTimeLength], 'nearest');
        
            % Normalization + inversion (same as training)
            S_norm = S_resized - min(S_resized(:));
            S_norm = S_norm / max(S_norm(:));
            img = 1 - S_norm;
            img = reshape(img, [spectrogramDimensions, 1]);
        
            %% 4. Predict using the CNN
            scores = predict(app.net.net, img);
            [~, idx] = max(scores(:));
            classes = app.net.net.Layers(end).Classes;
            predictedLabel = string(classes(idx));

        
            %% 5. Map CNN label to a symbol (digit/operator)
            symbol = mapPredictionToSymbol(predictedLabel);   % uses your existing function
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonPushed(app, event)
            if app.recording
                app.Button.Value = false;
                app.Button.Icon = "mic.svg";
                app.recording = false;

                if app.expression == ""
                    app.ExpressionLabel.Text = "Click the Record button to start recording...";
                end
            else
                app.Button.Value = true;
                app.Button.Icon = "stop.svg";
                app.recording = true;

                if app.expression == ""
                    app.ExpressionLabel.Text = "...";
                end

                app.lastToken = app.recognizeSpeechSymbol();

                if (app.lastToken ~= "=")
                    app.expression = strcat(app.expression, app.lastToken);
                    app.ExpressionLabel.Text = app.expression;
                else
                    app.ResultLabel.Text = strcat('= ', string(eval(app.expression)));
                end
                
                app.ButtonPushed(app, null);
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.expression = "";
            app.ExpressionLabel.Text = "Click the Record button to start recording...";
            app.recording = true;
            ButtonPushed(app,null);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExpressionLabel = uilabel(app.UIFigure);
            app.ExpressionLabel.Text = 'Click the Record button to start recording...';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'mic.svg');
            app.Button.Text = '';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = '';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end